classdef EQAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        dBLabel                     matlab.ui.control.Label
        dBFSLabel                   matlab.ui.control.Label
        Meter                       audio.ui.control.Meter
        StopRecordButton            matlab.ui.control.Button
        RecordButton                matlab.ui.control.Button
        OutGainSlider               matlab.ui.control.Slider
        OutGainSliderLabel          matlab.ui.control.Label
        HighCutFilterLabel          matlab.ui.control.Label
        LowCutFilterLabel           matlab.ui.control.Label
        BellFilterLabel             matlab.ui.control.Label
        InitialLabel                matlab.ui.control.Label
        PauseButton                 matlab.ui.control.Button
        HighCutFilterPanel          matlab.ui.container.Panel
        HC_CutOffFrequency_Label    matlab.ui.control.Label
        HC_Hz_Label                 matlab.ui.control.Label
        HC_Switch                   matlab.ui.control.Switch
        HC_Hz_EditField             matlab.ui.control.NumericEditField
        HC_slope_Buttons            matlab.ui.container.ButtonGroup
        HC_Slope_Label              matlab.ui.control.Label
        HC_eighteen_db              matlab.ui.control.RadioButton
        HC_twelve_db                matlab.ui.control.RadioButton
        HC_six_db                   matlab.ui.control.RadioButton
        HC_freq_Slider              matlab.ui.control.Slider
        EQOn_Label                  matlab.ui.control.Label
        EQOff_Label                 matlab.ui.control.Label
        On_Off_EQ                   matlab.ui.control.RockerSwitch
        StopButton                  matlab.ui.control.Button
        LoadButton                  matlab.ui.control.Button
        LoadedFile_Field            matlab.ui.control.EditField
        PlayButton                  matlab.ui.control.Button
        BellFilterPanel             matlab.ui.container.Panel
        B_TabGroup                  matlab.ui.container.TabGroup
        B_Tab_1                     matlab.ui.container.Tab
        B_Slope_Label_1             matlab.ui.control.Label
        B_QFactor_Label_1           matlab.ui.control.Label
        B_Gain_Label_1              matlab.ui.control.Label
        B_db_Label_1                matlab.ui.control.Label
        B_Hz_Label_1                matlab.ui.control.Label
        B_CenterFrequency_Label_1   matlab.ui.control.Label
        B_QFactor_EditField_1       matlab.ui.control.NumericEditField
        B_Gain_EditField_1          matlab.ui.control.NumericEditField
        B_CenterFreq_EditField_1    matlab.ui.control.NumericEditField
        B_QFactor_Knob_1            matlab.ui.control.Knob
        B_Switch_1                  matlab.ui.control.Switch
        B_Gain_Slider_1             matlab.ui.control.Slider
        B_CenterFrequency_Slider_1  matlab.ui.control.Slider
        B_Slope_Buttons_1           matlab.ui.container.ButtonGroup
        B_thirtysix_db_1            matlab.ui.control.RadioButton
        B_twentyfour_db_1           matlab.ui.control.RadioButton
        B_twelve_db_1               matlab.ui.control.RadioButton
        B_Tab_2                     matlab.ui.container.Tab
        B_Slope_Label_2             matlab.ui.control.Label
        B_QFactor_Label_2           matlab.ui.control.Label
        B_Gain_Label_2              matlab.ui.control.Label
        B_db_Label_2                matlab.ui.control.Label
        B_Hz_Label_2                matlab.ui.control.Label
        B_CenterFrequency_Label_2   matlab.ui.control.Label
        B_QFactor_EditField_2       matlab.ui.control.NumericEditField
        B_Gain_EditField_2          matlab.ui.control.NumericEditField
        B_CenterFreq_EditField_2    matlab.ui.control.NumericEditField
        B_QFactor_Knob_2            matlab.ui.control.Knob
        B_Switch_2                  matlab.ui.control.Switch
        B_Gain_Slider_2             matlab.ui.control.Slider
        B_CenterFrequency_Slider_2  matlab.ui.control.Slider
        B_Slope_Buttons_2           matlab.ui.container.ButtonGroup
        B_thirtysix_db_2            matlab.ui.control.RadioButton
        B_twentyfour_db_2           matlab.ui.control.RadioButton
        B_twelve_db_2               matlab.ui.control.RadioButton
        B_Tab_3                     matlab.ui.container.Tab
        B_Slope_Label_3             matlab.ui.control.Label
        B_QFactor_Label_3           matlab.ui.control.Label
        B_Gain_Label_3              matlab.ui.control.Label
        B_db_Label_3                matlab.ui.control.Label
        B_Hz_Label_3                matlab.ui.control.Label
        B_CenterFrequency_Label_3   matlab.ui.control.Label
        B_QFactor_EditField_3       matlab.ui.control.NumericEditField
        B_Gain_EditField_3          matlab.ui.control.NumericEditField
        B_CenterFreq_EditField_3    matlab.ui.control.NumericEditField
        B_QFactor_Knob_3            matlab.ui.control.Knob
        B_Switch_3                  matlab.ui.control.Switch
        B_Gain_Slider_3             matlab.ui.control.Slider
        B_CenterFrequency_Slider_3  matlab.ui.control.Slider
        B_Slope_Buttons_3           matlab.ui.container.ButtonGroup
        B_thirtysix_db_3            matlab.ui.control.RadioButton
        B_twentyfour_db_3           matlab.ui.control.RadioButton
        B_twelve_db_3               matlab.ui.control.RadioButton
        LowCutFilterPanel           matlab.ui.container.Panel
        LC_Slope_Label              matlab.ui.control.Label
        LC_CutOffFrequency_Label    matlab.ui.control.Label
        LC_Hz_Label                 matlab.ui.control.Label
        LC_Switch                   matlab.ui.control.Switch
        LC_Hz_EditField             matlab.ui.control.NumericEditField
        LC_slope_Buttons            matlab.ui.container.ButtonGroup
        LC_eighteen_db              matlab.ui.control.RadioButton
        LC_twelve_db                matlab.ui.control.RadioButton
        LC_six_db                   matlab.ui.control.RadioButton
        LC_freq_Slider              matlab.ui.control.Slider
        Panel                       matlab.ui.container.Panel
        FreqzAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fileReader                          %AudioFileReader
        deviceWriter                        %audioDeviceWriter
        lvlMeter                            %audioLevelMeter                          
        scope                               %Spectrum Analyzer
        filter                              %SOSFilter
        recObj                              %audiorecorder
        %Filters Objects
        LowCut
        BellVector = BellClass.empty;
        HighCut
        %Tot Coefficients
        NumeratorTot
        DenominatorTot
        %Variables
        sampleRate
        EQ_State = true;
        PlayRequested = false;
        StopRequested = false;
        PauseRequested = false;
        outGain = 1;
    end
    
    methods (Access = private)
        
        function renderFrequencyResponse(app)
              
                app.NumeratorTot = [];                                                                  
                app.DenominatorTot = [];

                if app.LowCut.getState() == true                                                        
                  app.NumeratorTot = app.LowCut.Numerator;
                  app.DenominatorTot = app.LowCut.Denominator;
                end
                for bell = app.BellVector                                                               
                    if bell.getState() == true
                        app.NumeratorTot = [app.NumeratorTot; bell.Numerator];           
                        app.DenominatorTot = [app.DenominatorTot; bell.Denominator];    
                    end
                end
                if app.HighCut.getState() == true                                                       
                    app.NumeratorTot = [app.NumeratorTot; app.HighCut.Numerator];        
                    app.DenominatorTot = [app.DenominatorTot; app.HighCut.Denominator]; 
                end
                
                if isempty(app.NumeratorTot) || isempty(app.DenominatorTot)                             
                    [app.NumeratorTot, app.DenominatorTot] = app.BellVector(1).getBypassFilter();
                end
                
                app.filter = dsp.SOSFilter(app.NumeratorTot, app.DenominatorTot);                       
               
                [H, F] = freqz(app.NumeratorTot, app.DenominatorTot, 8192, app.sampleRate);             
                
                slg = semilogx(app.FreqzAxes, F/1000, 20*log10(abs(H)));
                
                % da togliere
                %slg.LineWidth = 3;
                % ------------------------------------
        end
       
        
        function disableFiltersUIElements(app)
                app.LC_Switch.Value = 'Off';
                app.LC_freq_Slider.Enable = "off";
                app.LC_Hz_EditField.Enable = "off";
                app.LC_Hz_Label.Enable = "off";
                app.LC_slope_Buttons.Enable = "off";
                app.LC_Slope_Label.Enable = "off";
                app.LC_CutOffFrequency_Label.Enable = "off";
                
                for i=1:numel(app.B_TabGroup.Children)
                    app.("B_Switch_" + string(i)).Value = 'Off';
                    app.("B_CenterFrequency_Slider_"+ string(i)).Enable = "off";
                    app.("B_CenterFreq_EditField_"+ string(i)).Enable = "off";
                    app.("B_Gain_Slider_"+ string(i)).Enable = "off";
                    app.("B_Gain_EditField_"+ string(i)).Enable = "off";
                    app.("B_QFactor_Knob_"+ string(i)).Enable = "off";
                    app.("B_QFactor_EditField_"+ string(i)).Enable = "off";
                    app.("B_Slope_Buttons_"+ string(i)).Enable = "off";
                    app.("B_Slope_Label_"+ string(i)).Enable = "off";
                    app.("B_CenterFrequency_Label_"+ string(i)).Enable = "off";
                    app.("B_Hz_Label_"+ string(i)).Enable = "off";
                    app.("B_Gain_Label_"+ string(i)).Enable = "off";
                    app.("B_db_Label_"+ string(i)).Enable = "off";
                    app.("B_QFactor_Label_"+ string(i)).Enable = "off";
                end

                app.HC_Switch.Value = 'Off';
                app.HC_freq_Slider.Enable = "off";
                app.HC_Hz_EditField.Enable = "off";
                app.HC_Hz_Label.Enable = "off";
                app.HC_slope_Buttons.Enable = "off";
                app.HC_CutOffFrequency_Label.Enable = "off";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.LowCutFilterPanel.BorderColor = [0.90 0.90 0.90];
        app.HighCutFilterPanel.BorderColor = [0.90 0.90 0.90];
        
        xlabel(app.FreqzAxes, 'Frequency (kHz)');
        ylabel(app.FreqzAxes, 'Magnitude (dB)');
        title(app.FreqzAxes, 'Frequency Response', 'FontWeight','normal');
        grid(app.FreqzAxes, 'on');     
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)

            [file,location] = uigetfile;
            if file ~= 0
            app.LoadedFile_Field.Value = file;
            
            app.InitialLabel.Visible = "off";

            %Initialize fileReader e deviceWriter
            frameSize = 256;                           
            app.fileReader = dsp.AudioFileReader( ...
                [location, file], ...
                SamplesPerFrame=frameSize);
            app.sampleRate = app.fileReader.SampleRate;
            
            %Initialize lvlMeter
            app.lvlMeter = audioLevelMeter(SampleRate=app.sampleRate, ...
                                        WindowLength=app.fileReader.SamplesPerFrame);
            

            %Initialize filters
            CutOffFreq = app.LC_freq_Slider.Value;
            selectedSlope = app.LC_slope_Buttons.SelectedObject;
            app.LowCut = LowCutClass(app.sampleRate, CutOffFreq, selectedSlope);
           

            for i=1:numel(app.B_TabGroup.Children)
                CenterFreq = app.("B_CenterFrequency_Slider_" + string(i)).Value;
                Gain = app.("B_Gain_Slider_" + string(i)).Value;
                QFactor = app.("B_QFactor_Knob_" + string(i)).Value;
                selectedSlope = app.("B_Slope_Buttons_" + string(i)).SelectedObject;

                app.BellVector(i) = BellClass(app.sampleRate, CenterFreq, Gain, QFactor, selectedSlope);
            end

            CutOffFreq = app.HC_freq_Slider.Value;
            selectedSlope = app.HC_slope_Buttons.SelectedObject;
            app.HighCut = HighCutClass(app.sampleRate, CutOffFreq, selectedSlope);
            
            %Disable filters UI elements
            disableFiltersUIElements(app);

            %Enable Graphs
            app.FreqzAxes.Visible = "on";

            app.scope = spectrumAnalyzer( ...
                SampleRate=app.sampleRate, ...
                PlotAsTwoSidedSpectrum=false, ...
                FrequencyScale="log", ...
                Title="Original and Equalized Signals", ...
                ShowLegend=true, ...
                ChannelNames=["Original Signal","Equalized Signal"]);
            
            app.scope.Parent = app.Panel;
            
            app.scope.Parent = app.Panel;
            app.scope.BackgroundColor = [1 1 1]; 
            app.scope.AxesColor = [1 1 1];
            app.scope.FontColor = [0 0 0];
            app.scope.LineColor = [0.0 1.0 0.0
                                   1.0 0.0 1.0];
            %Da togliere
            %app.scope.LineWidth = 3;
            %----------------------------------------------------------------------------------------------------

            %Enable Control Options
            app.PlayButton.Enable = "on";
            app.StopButton.Enable = "on";
            app.EQOff_Label.Enable = "on";
            app.EQOn_Label.Enable = "on";
            app.On_Off_EQ.Enable = "on";
            app.LowCutFilterPanel.Enable = "on";
            app.LowCutFilterPanel.BorderColor = [0.80,0.80,0.80];
            app.BellFilterPanel.Enable = "on";
            app.HighCutFilterPanel.Enable = "on";
            app.HighCutFilterPanel.BorderColor = [0.80,0.80,0.80];
            app.LowCutFilterLabel.Enable = "on";
            app.BellFilterLabel.Enable = "on";
            app.HighCutFilterLabel.Enable = "on";
            
            %Render
            renderFrequencyResponse(app);

            end

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            
            app.PlayRequested = true;           
            app.RecordButton.Enable = "off";

            %Initialize deviceWriter 
            app.deviceWriter = audioDeviceWriter( ...
                SampleRate=app.sampleRate);
            
            %Enable Pause Button
            app.PlayButton.Enable = "off";
            app.PlayButton.Visible = "off";
            app.PauseButton.Enable = "on";
            app.PauseButton.Visible = "on";
            
            %Audio processing and playback cycle
            while ~isDone(app.fileReader) && app.StopRequested == false && app.PauseRequested == false
                
                drawnow limitrate;                                  
                
                frame = app.fileReader();                           
                
                equalizedFrame = app.filter(frame);                 
                
                app.scope([frame(:,1),equalizedFrame(:,1)]);        
                
                y1 = equalizedFrame(:,1)*app.outGain;               
                y2 = equalizedFrame(:,2)*app.outGain;
                equalizedFrame = [y1, y2];
                
                app.Meter.Value = app.lvlMeter(equalizedFrame);     

                if app.EQ_State == true                             
                    app.deviceWriter(equalizedFrame);
                else
                    app.deviceWriter(frame);
                end
                
            end
            
            app.PlayRequested = false;
            app.RecordButton.Enable = "on";

            %Enable PLay Button
            app.PlayButton.Enable = "on";
            app.PlayButton.Visible = "on";
            app.PauseButton.Enable = "off";
            app.PauseButton.Visible = "off";
            
            %Reset Meter
            app.Meter.Value = [-inf, -inf]; 
            
            %Manage cycle exits
            if app.StopRequested == true
                app.StopRequested = false;
                release(app.deviceWriter);
                reset(app.fileReader);
                reset(app.scope);
            end
            if app.PauseRequested == true
                app.PauseRequested = false;
                release(app.deviceWriter);
            end
            if isDone(app.fileReader)
                release(app.deviceWriter);
                reset(app.fileReader);
                reset(app.scope);
            end
            

            %release(app.fileReader);
            %release(scope);
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.PauseRequested = true;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopRequested = true;
            if app.PlayRequested == false
                reset(app.fileReader);
                reset(app.scope);
                app.StopRequested = false;
            end
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            app.RecordButton.Enable = "off";
            app.RecordButton.Visible = "off";
            app.StopRecordButton.Enable = "on";
            app.StopRecordButton.Visible = "on";
            
            Fs = 44100; 
            nBits = 16; 
            nChannels = 2;
            
            %Initalize recObj 
            app.recObj = audiorecorder(Fs,nBits,nChannels);

            %Start recording
            record(app.recObj);
        end

        % Button pushed function: StopRecordButton
        function StopRecordButtonPushed(app, event)
            %Stop recording
            stop(app.recObj);
            
            app.RecordButton.Enable = "on";
            app.RecordButton.Visible = "on";
            app.StopRecordButton.Enable = "off";
            app.StopRecordButton.Visible = "off";
            
            %Retrieve recording data
            y = getaudiodata(app.recObj);%, 'uint8'
            
            %Save the rec
            [filename, pathname] = uiputfile('*.wav', 'File name for output');
            if isnumeric(filename)  %user cancel
                return;
            end   
            fullname = fullfile(pathname, filename);
            audiowrite(fullname, y, 44100, 'BitsPerSample', 16);
        end

        % Value changed function: On_Off_EQ
        function On_Off_EQValueChanged2(app, event)
            value = app.On_Off_EQ.Value;
            if value == "EQ Off"
                 app.EQ_State = false;
                 app.EQOff_Label.FontWeight = 'bold';
                 app.EQOn_Label.FontWeight = 'normal';
                 
            else
                app.EQ_State = true;
                app.EQOff_Label.FontWeight = 'normal';
                app.EQOn_Label.FontWeight = 'bold';
            end
        end

        % Value changed function: OutGainSlider
        function OutGainSliderValueChanged(app, event)
            value = app.OutGainSlider.Value;
            valueLin = 10^(value/20);
            app.outGain = valueLin;
        end

        % Value changed function: LC_Switch
        function LC_SwitchValueChanged(app, event)
            value = app.LC_Switch.Value;
            if value == "On"
                app.LowCut.on();
                renderFrequencyResponse(app)
                app.LC_freq_Slider.Enable = "on";
                app.LC_Hz_EditField.Enable = "on";
                app.LC_Hz_Label.Enable = "on";
                app.LC_slope_Buttons.Enable = "on";
                app.LC_Slope_Label.Enable = "on";
                app.LC_CutOffFrequency_Label.Enable = "on";
            else
                app.LowCut.off();
                renderFrequencyResponse(app);
                app.LC_freq_Slider.Enable = "off";
                app.LC_Hz_EditField.Enable = "off";
                app.LC_Hz_Label.Enable = "off";
                app.LC_slope_Buttons.Enable = "off";
                app.LC_Slope_Label.Enable = "off";
                app.LC_CutOffFrequency_Label.Enable = "off";
            end
        end

        % Value changing function: LC_freq_Slider
        function LC_freq_SliderValueChanging(app, event)
            newCutOffFreq = round(event.Value, 2);
            app.LC_Hz_EditField.Value = newCutOffFreq;
            app.LowCut.setCutOffFreq(newCutOffFreq);
            renderFrequencyResponse(app);
        end

        % Value changed function: LC_Hz_EditField
        function LC_Hz_EditFieldValueChanged(app, event)
            newCutOffFreq = round(app.LC_Hz_EditField.Value, 2);
            app.LC_Hz_EditField.Value = newCutOffFreq;
            upperLimit = app.LC_freq_Slider.Limits(2);
            if (newCutOffFreq > upperLimit)
                app.LC_freq_Slider.Value = upperLimit;
            else
                app.LC_freq_Slider.Value = newCutOffFreq;
            end
            app.LowCut.setCutOffFreq(newCutOffFreq);
            renderFrequencyResponse(app);
        end

        % Selection changed function: LC_slope_Buttons
        function LC_slope_ButtonsSelectionChanged(app, event)
            selectedSlope = app.LC_slope_Buttons.SelectedObject;
            app.LowCut.setSlope(selectedSlope);
            renderFrequencyResponse(app);
        end

        % Value changed function: B_Switch_1
        function B_Switch_1ValueChanged(app, event)
            value = app.B_Switch_1.Value;
            if value == "On"
                app.BellVector(1).on();
                renderFrequencyResponse(app)
                app.B_CenterFrequency_Slider_1.Enable = "on";
                app.B_CenterFreq_EditField_1.Enable = "on";
                app.B_Gain_Slider_1.Enable = "on";
                app.B_Gain_EditField_1.Enable = "on";
                app.B_QFactor_Knob_1.Enable = "on";
                app.B_QFactor_EditField_1.Enable = "on";
                app.B_Slope_Buttons_1.Enable = "on";
                app.B_Slope_Label_1.Enable = "on";
                app.B_CenterFrequency_Label_1.Enable = "on";
                app.B_Hz_Label_1.Enable = "on";
                app.B_Gain_Label_1.Enable = "on";
                app.B_db_Label_1.Enable = "on";
                app.B_QFactor_Label_1.Enable = "on";
            else
                app.BellVector(1).off();
                renderFrequencyResponse(app);
                app.B_CenterFrequency_Slider_1.Enable = "off";
                app.B_CenterFreq_EditField_1.Enable = "off";
                app.B_Gain_Slider_1.Enable = "off";
                app.B_Gain_EditField_1.Enable = "off";
                app.B_QFactor_Knob_1.Enable = "off";
                app.B_QFactor_EditField_1.Enable = "off";
                app.B_Slope_Buttons_1.Enable = "off";
                app.B_Slope_Label_1.Enable = "off";
                app.B_CenterFrequency_Label_1.Enable = "off";
                app.B_Hz_Label_1.Enable = "off";
                app.B_Gain_Label_1.Enable = "off";
                app.B_db_Label_1.Enable = "off";
                app.B_QFactor_Label_1.Enable = "off";
            end
        end

        % Value changing function: B_CenterFrequency_Slider_1
        function B_CenterFrequency_Slider_1ValueChanging(app, event)
            newCenterFreq = round(event.Value, 2);
            app.B_CenterFreq_EditField_1.Value = newCenterFreq;
            app.BellVector(1).setCenterFreq(newCenterFreq);
            renderFrequencyResponse(app);
        end

        % Value changed function: B_CenterFreq_EditField_1
        function B_CenterFreq_EditField_1ValueChanged(app, event)
            newCenterFreq = round(app.B_CenterFreq_EditField_1.Value, 2);
            app.B_CenterFreq_EditField_1.Value = newCenterFreq;
            upperLimit = app.B_CenterFrequency_Slider_1.Limits(2);
            if (newCenterFreq > upperLimit)
                app.B_CenterFrequency_Slider_1.Value = upperLimit;
            else
                app.B_CenterFrequency_Slider_1.Value = newCenterFreq;
            end
            app.BellVector(1).setCenterFreq(newCenterFreq);
            renderFrequencyResponse(app);
        end

        % Value changing function: B_Gain_Slider_1
        function B_Gain_Slider_1ValueChanging(app, event)
            newGain = round(event.Value, 1);
            app.B_Gain_EditField_1.Value = newGain;
            app.BellVector(1).setGain(newGain);
            renderFrequencyResponse(app);
        end

        % Value changed function: B_Gain_EditField_1
        function B_Gain_EditField_1ValueChanged(app, event)
            newGain = round(app.B_Gain_EditField_1.Value, 1);
            app.B_Gain_EditField_1.Value = newGain;
            app.B_Gain_Slider_1.Value = newGain;
            app.BellVector(1).setGain(newGain);
            renderFrequencyResponse(app);
        end

        % Value changing function: B_QFactor_Knob_1
        function B_QFactor_Knob_1ValueChanging(app, event)
            newQFactor = round(event.Value, 1);
            app.B_QFactor_EditField_1.Value = newQFactor;
            app.BellVector(1).setQFactor(newQFactor);
            renderFrequencyResponse(app);
        end

        % Value changed function: B_QFactor_EditField_1
        function B_QFactor_EditField_1ValueChanged(app, event)
            newQFactor = round(app.B_QFactor_EditField_1.Value, 1);
            app.B_QFactor_EditField_1.Value = newQFactor;
            app.B_QFactor_Knob_1.Value = newQFactor;
            app.BellVector(1).setQFactor(newQFactor);
            renderFrequencyResponse(app);
        end

        % Selection changed function: B_Slope_Buttons_1
        function B_Slope_Buttons_1SelectionChanged(app, event)
            selectedSlope = app.B_Slope_Buttons_1.SelectedObject;
            app.BellVector(1).setSlope(selectedSlope);
            renderFrequencyResponse(app);  
        end

        % Value changed function: B_Switch_2
        function B_Switch_2ValueChanged(app, event)
            value = app.B_Switch_2.Value;
            if value == "On"
                app.BellVector(2).on();
                renderFrequencyResponse(app)
                app.B_CenterFrequency_Slider_2.Enable = "on";
                app.B_CenterFreq_EditField_2.Enable = "on";
                app.B_Gain_Slider_2.Enable = "on";
                app.B_Gain_EditField_2.Enable = "on";
                app.B_QFactor_Knob_2.Enable = "on";
                app.B_QFactor_EditField_2.Enable = "on";
                app.B_Slope_Buttons_2.Enable = "on";
                app.B_Slope_Label_2.Enable = "on";
                app.B_CenterFrequency_Label_2.Enable = "on";
                app.B_Hz_Label_2.Enable = "on";
                app.B_Gain_Label_2.Enable = "on";
                app.B_db_Label_2.Enable = "on";
                app.B_QFactor_Label_2.Enable = "on";
            else
                app.BellVector(2).off();
                renderFrequencyResponse(app);
                app.B_CenterFrequency_Slider_2.Enable = "off";
                app.B_CenterFreq_EditField_2.Enable = "off";
                app.B_Gain_Slider_2.Enable = "off";
                app.B_Gain_EditField_2.Enable = "off";
                app.B_QFactor_Knob_2.Enable = "off";
                app.B_QFactor_EditField_2.Enable = "off";
                app.B_Slope_Buttons_2.Enable = "off";
                app.B_Slope_Label_2.Enable = "off";
                app.B_CenterFrequency_Label_2.Enable = "off";
                app.B_Hz_Label_2.Enable = "off";
                app.B_Gain_Label_2.Enable = "off";
                app.B_db_Label_2.Enable = "off";
                app.B_QFactor_Label_2.Enable = "off";
            end
        end

        % Value changing function: B_CenterFrequency_Slider_2
        function B_CenterFrequency_Slider_2ValueChanging(app, event)
            newCenterFreq = round(event.Value, 2);
            app.B_CenterFreq_EditField_2.Value = newCenterFreq;
            app.BellVector(2).setCenterFreq(newCenterFreq);
            renderFrequencyResponse(app);
        end

        % Value changed function: B_CenterFreq_EditField_2
        function B_CenterFreq_EditField_2ValueChanged(app, event)
            newCenterFreq = round(app.B_CenterFreq_EditField_2.Value, 2);
            app.B_CenterFreq_EditField_2.Value = newCenterFreq;
            upperLimit = app.B_CenterFrequency_Slider_2.Limits(2);
            if (newCenterFreq > upperLimit)
                app.B_CenterFrequency_Slider_2.Value = upperLimit;
            else
                app.B_CenterFrequency_Slider_2.Value = newCenterFreq;
            end
            app.BellVector(2).setCenterFreq(newCenterFreq);
            renderFrequencyResponse(app);
        end

        % Value changing function: B_Gain_Slider_2
        function B_Gain_Slider_2ValueChanging(app, event)
            newGain = round(event.Value, 1);
            app.B_Gain_EditField_2.Value = newGain;
            app.BellVector(2).setGain(newGain);
            renderFrequencyResponse(app);
        end

        % Value changed function: B_Gain_EditField_2
        function B_Gain_EditField_2ValueChanged(app, event)
            newGain = round(app.B_Gain_EditField_2.Value, 1);
            app.B_Gain_EditField_2.Value = newGain;
            app.B_Gain_Slider_2.Value = newGain;
            app.BellVector(2).setGain(newGain);
            renderFrequencyResponse(app);
        end

        % Value changing function: B_QFactor_Knob_2
        function B_QFactor_Knob_2ValueChanging(app, event)
            newQFactor = round(event.Value, 1);
            app.B_QFactor_EditField_2.Value = newQFactor;
            app.BellVector(2).setQFactor(newQFactor);
            renderFrequencyResponse(app);
        end

        % Value changed function: B_QFactor_EditField_2
        function B_QFactor_EditField_2ValueChanged(app, event)
            newQFactor = round(app.B_QFactor_EditField_2.Value, 1);
            app.B_QFactor_EditField_2.Value = newQFactor;
            app.B_QFactor_Knob_2.Value = newQFactor;
            app.BellVector(2).setQFactor(newQFactor);
            renderFrequencyResponse(app);
        end

        % Selection changed function: B_Slope_Buttons_2
        function B_Slope_Buttons_2SelectionChanged(app, event)
            selectedSlope = app.B_Slope_Buttons_2.SelectedObject;
            app.BellVector(2).setSlope(selectedSlope);
            renderFrequencyResponse(app);
        end

        % Value changed function: B_Switch_3
        function B_Switch_3ValueChanged(app, event)
            value = app.B_Switch_3.Value;
            if value == "On"
                app.BellVector(3).on();
                renderFrequencyResponse(app)
                app.B_CenterFrequency_Slider_3.Enable = "on";
                app.B_CenterFreq_EditField_3.Enable = "on";
                app.B_Gain_Slider_3.Enable = "on";
                app.B_Gain_EditField_3.Enable = "on";
                app.B_QFactor_Knob_3.Enable = "on";
                app.B_QFactor_EditField_3.Enable = "on";
                app.B_Slope_Buttons_3.Enable = "on";
                app.B_Slope_Label_3.Enable = "on";
                app.B_CenterFrequency_Label_3.Enable = "on";
                app.B_Hz_Label_3.Enable = "on";
                app.B_Gain_Label_3.Enable = "on";
                app.B_db_Label_3.Enable = "on";
                app.B_QFactor_Label_3.Enable = "on";
            else
                app.BellVector(3).off();
                renderFrequencyResponse(app);
                app.B_CenterFrequency_Slider_3.Enable = "off";
                app.B_CenterFreq_EditField_3.Enable = "off";
                app.B_Gain_Slider_3.Enable = "off";
                app.B_Gain_EditField_3.Enable = "off";
                app.B_QFactor_Knob_3.Enable = "off";
                app.B_QFactor_EditField_3.Enable = "off";
                app.B_Slope_Buttons_3.Enable = "off";
                app.B_Slope_Label_3.Enable = "off";
                app.B_CenterFrequency_Label_3.Enable = "off";
                app.B_Hz_Label_3.Enable = "off";
                app.B_Gain_Label_3.Enable = "off";
                app.B_db_Label_3.Enable = "off";
                app.B_QFactor_Label_3.Enable = "off";
            end
        end

        % Value changing function: B_CenterFrequency_Slider_3
        function B_CenterFrequency_Slider_3ValueChanging(app, event)
            newCenterFreq = round(event.Value, 2);
            app.B_CenterFreq_EditField_3.Value = newCenterFreq;
            app.BellVector(3).setCenterFreq(newCenterFreq);
            renderFrequencyResponse(app);
        end

        % Value changed function: B_CenterFreq_EditField_3
        function B_CenterFreq_EditField_3ValueChanged(app, event)
            newCenterFreq = round(app.B_CenterFreq_EditField_3.Value, 2);
            app.B_CenterFreq_EditField_3.Value = newCenterFreq;
            upperLimit = app.B_CenterFrequency_Slider_3.Limits(2);
            if (newCenterFreq > upperLimit)
                app.B_CenterFrequency_Slider_3.Value = upperLimit;
            else
                app.B_CenterFrequency_Slider_3.Value = newCenterFreq;
            end
            app.BellVector(3).setCenterFreq(newCenterFreq);
            renderFrequencyResponse(app);
        end

        % Value changing function: B_Gain_Slider_3
        function B_Gain_Slider_3ValueChanging(app, event)
            newGain = round(event.Value, 1);
            app.B_Gain_EditField_3.Value = newGain;
            app.BellVector(3).setGain(newGain);
            renderFrequencyResponse(app);
        end

        % Value changed function: B_Gain_EditField_3
        function B_Gain_EditField_3ValueChanged(app, event)
            newGain = round(app.B_Gain_EditField_3.Value, 1);
            app.B_Gain_EditField_3.Value = newGain;
            app.B_Gain_Slider_3.Value = newGain;
            app.BellVector(3).setGain(newGain);
            renderFrequencyResponse(app);
        end

        % Value changing function: B_QFactor_Knob_3
        function B_QFactor_Knob_3ValueChanging(app, event)
            newQFactor = round(event.Value, 1);
            app.B_QFactor_EditField_3.Value = newQFactor;
            app.BellVector(3).setQFactor(newQFactor);
            renderFrequencyResponse(app);
        end

        % Value changed function: B_QFactor_EditField_3
        function B_QFactor_EditField_3ValueChanged(app, event)
            newQFactor = round(app.B_QFactor_EditField_3.Value, 1);
            app.B_QFactor_EditField_3.Value = newQFactor;
            app.B_QFactor_Knob_3.Value = newQFactor;
            app.BellVector(3).setQFactor(newQFactor);
            renderFrequencyResponse(app);
        end

        % Selection changed function: B_Slope_Buttons_3
        function B_Slope_Buttons_3SelectionChanged(app, event)
            selectedSlope = app.B_Slope_Buttons_3.SelectedObject;
            app.BellVector(3).setSlope(selectedSlope);
            renderFrequencyResponse(app); 
        end

        % Value changed function: HC_Switch
        function HC_SwitchValueChanged(app, event)
            value = app.HC_Switch.Value;
            if value == "On"
                app.HighCut.on();
                renderFrequencyResponse(app)
                app.HC_freq_Slider.Enable = "on";
                app.HC_Hz_EditField.Enable = "on";
                app.HC_Hz_Label.Enable = "on";
                app.HC_slope_Buttons.Enable = "on";
                app.HC_CutOffFrequency_Label.Enable = "on";
            else
                app.HighCut.off();
                renderFrequencyResponse(app);
                app.HC_freq_Slider.Enable = "off";
                app.HC_Hz_EditField.Enable = "off";
                app.HC_Hz_Label.Enable = "off";
                app.HC_slope_Buttons.Enable = "off";
                app.HC_CutOffFrequency_Label.Enable = "off";
            end
        end

        % Value changing function: HC_freq_Slider
        function HC_freq_SliderValueChanging(app, event)
            newCutOffFreq = round(event.Value, 2);
            app.HC_Hz_EditField.Value = newCutOffFreq;
            app.HighCut.setCutOffFreq(newCutOffFreq);
            renderFrequencyResponse(app);
        end

        % Value changed function: HC_Hz_EditField
        function HC_Hz_EditFieldValueChanged(app, event)
            newCutOffFreq = round(app.HC_Hz_EditField.Value, 2);
            app.HC_Hz_EditField.Value = newCutOffFreq;
            lowerLimit = app.HC_freq_Slider.Limits(1);
            if (newCutOffFreq < lowerLimit)
                app.HC_freq_Slider.Value = lowerLimit;
            else
                app.HC_freq_Slider.Value = newCutOffFreq;
            end
            app.HighCut.setCutOffFreq(newCutOffFreq);
            renderFrequencyResponse(app);
        end

        % Selection changed function: HC_slope_Buttons
        function HC_slope_ButtonsSelectionChanged(app, event)
            selectedSlope = app.HC_slope_Buttons.SelectedObject;
            app.HighCut.setSlope(selectedSlope);
            renderFrequencyResponse(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FreqzAxes = uiaxes(app.UIFigure);
            title(app.FreqzAxes, 'Title')
            xlabel(app.FreqzAxes, 'X')
            ylabel(app.FreqzAxes, 'Y')
            zlabel(app.FreqzAxes, 'Z')
            app.FreqzAxes.Visible = 'off';
            app.Panel = uipanel(app.UIFigure);
            app.LowCutFilterPanel = uipanel(app.UIFigure);
            app.LowCutFilterPanel.Enable = 'off';
            app.LC_freq_Slider = uislider(app.LowCutFilterPanel);
            app.LC_freq_Slider.Limits = [30 10000];
            app.LC_freq_Slider.MajorTicks = [30 5000 10000];
            app.LC_freq_Slider.MajorTickLabels = {'30', '5000', '10000'};
            app.LC_freq_Slider.ValueChangingFcn = createCallbackFcn(app, @LC_freq_SliderValueChanging, true);
            app.LC_freq_Slider.Enable = 'off';
            app.LC_freq_Slider.Value = 150;
            app.LC_slope_Buttons = uibuttongroup(app.LowCutFilterPanel);
            app.LC_slope_Buttons.SelectionChangedFcn = createCallbackFcn(app, @LC_slope_ButtonsSelectionChanged, true);
            app.LC_slope_Buttons.Enable = 'off';
            app.LC_six_db = uiradiobutton(app.LC_slope_Buttons);
            app.LC_six_db.Text = '6 dB/oct';
            app.LC_six_db.Value = true;
            app.LC_twelve_db = uiradiobutton(app.LC_slope_Buttons);
            app.LC_twelve_db.Text = '12 dB/oct';
            app.LC_eighteen_db = uiradiobutton(app.LC_slope_Buttons);
            app.LC_eighteen_db.Text = '18 dB/oct';
            app.LC_Hz_EditField = uieditfield(app.LowCutFilterPanel, 'numeric');
            app.LC_Hz_EditField.Limits = [30 22050];
            app.LC_Hz_EditField.ValueDisplayFormat = '%.7g';
            app.LC_Hz_EditField.ValueChangedFcn = createCallbackFcn(app, @LC_Hz_EditFieldValueChanged, true);
            app.LC_Hz_EditField.Enable = 'off';
            app.LC_Hz_EditField.Value = 150;
            app.LC_Switch = uiswitch(app.LowCutFilterPanel, 'slider');
            app.LC_Switch.ValueChangedFcn = createCallbackFcn(app, @LC_SwitchValueChanged, true);
            app.LC_Hz_Label = uilabel(app.LowCutFilterPanel);
            app.LC_Hz_Label.Enable = 'off';
            app.LC_Hz_Label.Text = 'Hz';
            app.LC_CutOffFrequency_Label = uilabel(app.LowCutFilterPanel);
            app.LC_CutOffFrequency_Label.Enable = 'off';
            app.LC_CutOffFrequency_Label.Text = 'CutOff Frequency';
            app.LC_Slope_Label = uilabel(app.LowCutFilterPanel);
            app.LC_Slope_Label.Enable = 'off';
            app.LC_Slope_Label.Text = 'Slope';
            app.BellFilterPanel = uipanel(app.UIFigure);
            app.BellFilterPanel.Enable = 'off';
            app.B_TabGroup = uitabgroup(app.BellFilterPanel);
            app.B_Tab_1 = uitab(app.B_TabGroup);
            app.B_Tab_1.Title = '1';
            app.B_Slope_Buttons_1 = uibuttongroup(app.B_Tab_1);
            app.B_Slope_Buttons_1.SelectionChangedFcn = createCallbackFcn(app, @B_Slope_Buttons_1SelectionChanged, true);
            app.B_Slope_Buttons_1.Enable = 'off';
            app.B_twelve_db_1 = uiradiobutton(app.B_Slope_Buttons_1);
            app.B_twelve_db_1.Text = '12 dB/oct';
            app.B_twelve_db_1.Value = true;
            app.B_twentyfour_db_1 = uiradiobutton(app.B_Slope_Buttons_1);
            app.B_twentyfour_db_1.Text = '24 dB/oct';
            app.B_thirtysix_db_1 = uiradiobutton(app.B_Slope_Buttons_1);
            app.B_thirtysix_db_1.Text = '36 dB/oct';
            app.B_CenterFrequency_Slider_1 = uislider(app.B_Tab_1);
            app.B_CenterFrequency_Slider_1.Limits = [30 10000];
            app.B_CenterFrequency_Slider_1.MajorTicks = [30 5000 10000];
            app.B_CenterFrequency_Slider_1.MajorTickLabels = {'30', '5000', '10000'};
            app.B_CenterFrequency_Slider_1.ValueChangingFcn = createCallbackFcn(app, @B_CenterFrequency_Slider_1ValueChanging, true);
            app.B_CenterFrequency_Slider_1.Enable = 'off';
            app.B_CenterFrequency_Slider_1.Value = 2500;
            app.B_Gain_Slider_1 = uislider(app.B_Tab_1);
            app.B_Gain_Slider_1.Limits = [-20 20];
            app.B_Gain_Slider_1.ValueChangingFcn = createCallbackFcn(app, @B_Gain_Slider_1ValueChanging, true);
            app.B_Gain_Slider_1.Enable = 'off';
            app.B_Gain_Slider_1.Value = 5;
            app.B_Switch_1 = uiswitch(app.B_Tab_1, 'slider');
            app.B_Switch_1.ValueChangedFcn = createCallbackFcn(app, @B_Switch_1ValueChanged, true);
            app.B_QFactor_Knob_1 = uiknob(app.B_Tab_1, 'continuous');
            app.B_QFactor_Knob_1.Limits = [0.5 5];
            app.B_QFactor_Knob_1.MajorTicks = [0.5 1 1.4 2.3 3.2 4.1 5];
            app.B_QFactor_Knob_1.ValueChangingFcn = createCallbackFcn(app, @B_QFactor_Knob_1ValueChanging, true);
            app.B_QFactor_Knob_1.Enable = 'off';
            app.B_QFactor_Knob_1.Value = 1;
            app.B_CenterFreq_EditField_1 = uieditfield(app.B_Tab_1, 'numeric');
            app.B_CenterFreq_EditField_1.Limits = [30 22050];
            app.B_CenterFreq_EditField_1.ValueDisplayFormat = '%.7g';
            app.B_CenterFreq_EditField_1.ValueChangedFcn = createCallbackFcn(app, @B_CenterFreq_EditField_1ValueChanged, true);
            app.B_CenterFreq_EditField_1.Enable = 'off';
            app.B_CenterFreq_EditField_1.Value = 2500;
            app.B_Gain_EditField_1 = uieditfield(app.B_Tab_1, 'numeric');
            app.B_Gain_EditField_1.Limits = [-20 20];
            app.B_Gain_EditField_1.ValueChangedFcn = createCallbackFcn(app, @B_Gain_EditField_1ValueChanged, true);
            app.B_Gain_EditField_1.Enable = 'off';
            app.B_Gain_EditField_1.Value = 5;
            app.B_QFactor_EditField_1 = uieditfield(app.B_Tab_1, 'numeric');
            app.B_QFactor_EditField_1.Limits = [0.5 5];
            app.B_QFactor_EditField_1.ValueChangedFcn = createCallbackFcn(app, @B_QFactor_EditField_1ValueChanged, true);
            app.B_QFactor_EditField_1.Enable = 'off';
            app.B_QFactor_EditField_1.Value = 1;
            app.B_CenterFrequency_Label_1 = uilabel(app.B_Tab_1);
            app.B_CenterFrequency_Label_1.Enable = 'off';
            app.B_CenterFrequency_Label_1.Text = 'Center Frequency';
            app.B_Hz_Label_1 = uilabel(app.B_Tab_1);
            app.B_Hz_Label_1.Enable = 'off';
            app.B_Hz_Label_1.Text = 'Hz';
            app.B_db_Label_1 = uilabel(app.B_Tab_1);
            app.B_db_Label_1.Enable = 'off';
            app.B_db_Label_1.Text = 'dB';
            app.B_Gain_Label_1 = uilabel(app.B_Tab_1);
            app.B_Gain_Label_1.Enable = 'off';
            app.B_Gain_Label_1.Text = 'Gain';
            app.B_QFactor_Label_1 = uilabel(app.B_Tab_1);
            app.B_QFactor_Label_1.Enable = 'off';
            app.B_QFactor_Label_1.Text = 'Q Factor';
            app.B_Slope_Label_1 = uilabel(app.B_Tab_1);
            app.B_Slope_Label_1.Enable = 'off';
            app.B_Slope_Label_1.Text = 'Slope';
            app.B_Tab_2 = uitab(app.B_TabGroup);
            app.B_Tab_2.Title = '2';
            app.B_Slope_Buttons_2 = uibuttongroup(app.B_Tab_2);
            app.B_Slope_Buttons_2.SelectionChangedFcn = createCallbackFcn(app, @B_Slope_Buttons_2SelectionChanged, true);
            app.B_Slope_Buttons_2.Enable = 'off';
            app.B_twelve_db_2 = uiradiobutton(app.B_Slope_Buttons_2);
            app.B_twelve_db_2.Text = '12 dB/oct';
            app.B_twelve_db_2.Value = true;
            app.B_twentyfour_db_2 = uiradiobutton(app.B_Slope_Buttons_2);
            app.B_twentyfour_db_2.Text = '24 dB/oct';
            app.B_thirtysix_db_2 = uiradiobutton(app.B_Slope_Buttons_2);
            app.B_thirtysix_db_2.Text = '36 dB/oct';
            app.B_CenterFrequency_Slider_2 = uislider(app.B_Tab_2);
            app.B_CenterFrequency_Slider_2.Limits = [30 10000];
            app.B_CenterFrequency_Slider_2.MajorTicks = [30 5000 10000];
            app.B_CenterFrequency_Slider_2.MajorTickLabels = {'30', '5000', '10000'};
            app.B_CenterFrequency_Slider_2.ValueChangingFcn = createCallbackFcn(app, @B_CenterFrequency_Slider_2ValueChanging, true);
            app.B_CenterFrequency_Slider_2.Enable = 'off';
            app.B_CenterFrequency_Slider_2.Value = 5000;
            app.B_Gain_Slider_2 = uislider(app.B_Tab_2);
            app.B_Gain_Slider_2.Limits = [-20 20];
            app.B_Gain_Slider_2.ValueChangingFcn = createCallbackFcn(app, @B_Gain_Slider_2ValueChanging, true);
            app.B_Gain_Slider_2.Enable = 'off';
            app.B_Gain_Slider_2.Value = 5;
            app.B_Switch_2 = uiswitch(app.B_Tab_2, 'slider');
            app.B_Switch_2.ValueChangedFcn = createCallbackFcn(app, @B_Switch_2ValueChanged, true);
            app.B_QFactor_Knob_2 = uiknob(app.B_Tab_2, 'continuous');
            app.B_QFactor_Knob_2.Limits = [0.5 5];
            app.B_QFactor_Knob_2.MajorTicks = [0.5 1 1.4 2.3 3.2 4.1 5];
            app.B_QFactor_Knob_2.ValueChangingFcn = createCallbackFcn(app, @B_QFactor_Knob_2ValueChanging, true);
            app.B_QFactor_Knob_2.Enable = 'off';
            app.B_QFactor_Knob_2.Value = 1;
            app.B_CenterFreq_EditField_2 = uieditfield(app.B_Tab_2, 'numeric');
            app.B_CenterFreq_EditField_2.Limits = [30 22050];
            app.B_CenterFreq_EditField_2.ValueDisplayFormat = '%.7g';
            app.B_CenterFreq_EditField_2.ValueChangedFcn = createCallbackFcn(app, @B_CenterFreq_EditField_2ValueChanged, true);
            app.B_CenterFreq_EditField_2.Enable = 'off';
            app.B_CenterFreq_EditField_2.Value = 5000;
            app.B_Gain_EditField_2 = uieditfield(app.B_Tab_2, 'numeric');
            app.B_Gain_EditField_2.Limits = [-20 20];
            app.B_Gain_EditField_2.ValueChangedFcn = createCallbackFcn(app, @B_Gain_EditField_2ValueChanged, true);
            app.B_Gain_EditField_2.Enable = 'off';
            app.B_Gain_EditField_2.Value = 5;
            app.B_QFactor_EditField_2 = uieditfield(app.B_Tab_2, 'numeric');
            app.B_QFactor_EditField_2.Limits = [0.5 5];
            app.B_QFactor_EditField_2.ValueChangedFcn = createCallbackFcn(app, @B_QFactor_EditField_2ValueChanged, true);
            app.B_QFactor_EditField_2.Enable = 'off';
            app.B_QFactor_EditField_2.Value = 1;
            app.B_CenterFrequency_Label_2 = uilabel(app.B_Tab_2);
            app.B_CenterFrequency_Label_2.Enable = 'off';
            app.B_CenterFrequency_Label_2.Text = 'Center Frequency';
            app.B_Hz_Label_2 = uilabel(app.B_Tab_2);
            app.B_Hz_Label_2.Enable = 'off';
            app.B_Hz_Label_2.Text = 'Hz';
            app.B_db_Label_2 = uilabel(app.B_Tab_2);
            app.B_db_Label_2.Enable = 'off';
            app.B_db_Label_2.Text = 'dB';
            app.B_Gain_Label_2 = uilabel(app.B_Tab_2);
            app.B_Gain_Label_2.Enable = 'off';
            app.B_Gain_Label_2.Text = 'Gain';
            app.B_QFactor_Label_2 = uilabel(app.B_Tab_2);
            app.B_QFactor_Label_2.Enable = 'off';
            app.B_QFactor_Label_2.Text = 'Q Factor';
            app.B_Slope_Label_2 = uilabel(app.B_Tab_2);
            app.B_Slope_Label_2.Enable = 'off';
            app.B_Slope_Label_2.Text = 'Slope';
            app.B_Tab_3 = uitab(app.B_TabGroup);
            app.B_Tab_3.Title = '3';
            app.B_Slope_Buttons_3 = uibuttongroup(app.B_Tab_3);
            app.B_Slope_Buttons_3.SelectionChangedFcn = createCallbackFcn(app, @B_Slope_Buttons_3SelectionChanged, true);
            app.B_Slope_Buttons_3.Enable = 'off';
            app.B_twelve_db_3 = uiradiobutton(app.B_Slope_Buttons_3);
            app.B_twelve_db_3.Text = '12 dB/oct';
            app.B_twelve_db_3.Value = true;
            app.B_twentyfour_db_3 = uiradiobutton(app.B_Slope_Buttons_3);
            app.B_twentyfour_db_3.Text = '24 dB/oct';
            app.B_thirtysix_db_3 = uiradiobutton(app.B_Slope_Buttons_3);
            app.B_thirtysix_db_3.Text = '36 dB/oct';
            app.B_CenterFrequency_Slider_3 = uislider(app.B_Tab_3);
            app.B_CenterFrequency_Slider_3.Limits = [30 10000];
            app.B_CenterFrequency_Slider_3.MajorTicks = [30 5000 10000];
            app.B_CenterFrequency_Slider_3.MajorTickLabels = {'30', '5000', '10000'};
            app.B_CenterFrequency_Slider_3.ValueChangingFcn = createCallbackFcn(app, @B_CenterFrequency_Slider_3ValueChanging, true);
            app.B_CenterFrequency_Slider_3.Enable = 'off';
            app.B_CenterFrequency_Slider_3.Value = 7000;
            app.B_Gain_Slider_3 = uislider(app.B_Tab_3);
            app.B_Gain_Slider_3.Limits = [-20 20];
            app.B_Gain_Slider_3.ValueChangingFcn = createCallbackFcn(app, @B_Gain_Slider_3ValueChanging, true);
            app.B_Gain_Slider_3.Enable = 'off';
            app.B_Gain_Slider_3.Value = 5;
            app.B_Switch_3 = uiswitch(app.B_Tab_3, 'slider');
            app.B_Switch_3.ValueChangedFcn = createCallbackFcn(app, @B_Switch_3ValueChanged, true);
            app.B_QFactor_Knob_3 = uiknob(app.B_Tab_3, 'continuous');
            app.B_QFactor_Knob_3.Limits = [0.5 5];
            app.B_QFactor_Knob_3.MajorTicks = [0.5 1 1.4 2.3 3.2 4.1 5];
            app.B_QFactor_Knob_3.ValueChangingFcn = createCallbackFcn(app, @B_QFactor_Knob_3ValueChanging, true);
            app.B_QFactor_Knob_3.Enable = 'off';
            app.B_QFactor_Knob_3.Value = 1;
            app.B_CenterFreq_EditField_3 = uieditfield(app.B_Tab_3, 'numeric');
            app.B_CenterFreq_EditField_3.Limits = [30 22050];
            app.B_CenterFreq_EditField_3.ValueDisplayFormat = '%.7g';
            app.B_CenterFreq_EditField_3.ValueChangedFcn = createCallbackFcn(app, @B_CenterFreq_EditField_3ValueChanged, true);
            app.B_CenterFreq_EditField_3.Enable = 'off';
            app.B_CenterFreq_EditField_3.Value = 7000;
            app.B_Gain_EditField_3 = uieditfield(app.B_Tab_3, 'numeric');
            app.B_Gain_EditField_3.Limits = [-20 20];
            app.B_Gain_EditField_3.ValueChangedFcn = createCallbackFcn(app, @B_Gain_EditField_3ValueChanged, true);
            app.B_Gain_EditField_3.Enable = 'off';
            app.B_Gain_EditField_3.Value = 5;
            app.B_QFactor_EditField_3 = uieditfield(app.B_Tab_3, 'numeric');
            app.B_QFactor_EditField_3.Limits = [0.5 5];
            app.B_QFactor_EditField_3.ValueChangedFcn = createCallbackFcn(app, @B_QFactor_EditField_3ValueChanged, true);
            app.B_QFactor_EditField_3.Enable = 'off';
            app.B_QFactor_EditField_3.Value = 1;
            app.B_CenterFrequency_Label_3 = uilabel(app.B_Tab_3);
            app.B_CenterFrequency_Label_3.Enable = 'off';
            app.B_CenterFrequency_Label_3.Text = 'Center Frequency';
            app.B_Hz_Label_3 = uilabel(app.B_Tab_3);
            app.B_Hz_Label_3.Enable = 'off';
            app.B_Hz_Label_3.Text = 'Hz';
            app.B_db_Label_3 = uilabel(app.B_Tab_3);
            app.B_db_Label_3.Enable = 'off';
            app.B_db_Label_3.Text = 'dB';
            app.B_Gain_Label_3 = uilabel(app.B_Tab_3);
            app.B_Gain_Label_3.Enable = 'off';
            app.B_Gain_Label_3.Text = 'Gain';
            app.B_QFactor_Label_3 = uilabel(app.B_Tab_3);
            app.B_QFactor_Label_3.Enable = 'off';
            app.B_QFactor_Label_3.Text = 'Q Factor';
            app.B_Slope_Label_3 = uilabel(app.B_Tab_3);
            app.B_Slope_Label_3.Enable = 'off';
            app.B_Slope_Label_3.Text = 'Slope';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = fullfile(pathToMLAPP, 'Icons', 'Play_Icon.png');
            app.PlayButton.Enable = 'off';
            app.PlayButton.Text = '';
            app.LoadedFile_Field = uieditfield(app.UIFigure, 'text');
            app.LoadedFile_Field.Editable = 'off';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = fullfile(pathToMLAPP, 'Icons', 'Stop_Icon.png');
            app.StopButton.Enable = 'off';
            app.StopButton.Text = '';
            app.On_Off_EQ = uiswitch(app.UIFigure, 'rocker');
            app.On_Off_EQ.Items = {'EQ On', 'EQ Off'};
            app.On_Off_EQ.ValueChangedFcn = createCallbackFcn(app, @On_Off_EQValueChanged2, true);
            app.On_Off_EQ.Enable = 'off';
            app.On_Off_EQ.Value = 'EQ On';
            app.EQOff_Label = uilabel(app.UIFigure);
            app.EQOff_Label.Enable = 'off';
            app.EQOff_Label.Text = 'EQ Off';
            app.EQOn_Label = uilabel(app.UIFigure);
            app.EQOn_Label.Enable = 'off';
            app.EQOn_Label.Text = 'EQ On';
            app.HighCutFilterPanel = uipanel(app.UIFigure);
            app.HighCutFilterPanel.Enable = 'off';
            app.HC_freq_Slider = uislider(app.HighCutFilterPanel);
            app.HC_freq_Slider.Limits = [100 22050];
            app.HC_freq_Slider.MajorTicks = [100 11000 22050];
            app.HC_freq_Slider.MajorTickLabels = {'100', '11000', '22050'};
            app.HC_freq_Slider.ValueChangingFcn = createCallbackFcn(app, @HC_freq_SliderValueChanging, true);
            app.HC_freq_Slider.Enable = 'off';
            app.HC_freq_Slider.Value = 10000;
            app.HC_slope_Buttons = uibuttongroup(app.HighCutFilterPanel);
            app.HC_slope_Buttons.SelectionChangedFcn = createCallbackFcn(app, @HC_slope_ButtonsSelectionChanged, true);
            app.HC_slope_Buttons.Enable = 'off';
            app.HC_six_db = uiradiobutton(app.HC_slope_Buttons);
            app.HC_six_db.Text = '6 dB/oct';
            app.HC_six_db.Value = true;
            app.HC_twelve_db = uiradiobutton(app.HC_slope_Buttons);
            app.HC_twelve_db.Text = '12 dB/oct';
            app.HC_eighteen_db = uiradiobutton(app.HC_slope_Buttons);
            app.HC_eighteen_db.Text = '18 dB/oct';
            app.HC_Slope_Label = uilabel(app.HC_slope_Buttons);
            app.HC_Slope_Label.Text = 'Slope';
            app.HC_Hz_EditField = uieditfield(app.HighCutFilterPanel, 'numeric');
            app.HC_Hz_EditField.Limits = [30 22050];
            app.HC_Hz_EditField.ValueDisplayFormat = '%.7g';
            app.HC_Hz_EditField.ValueChangedFcn = createCallbackFcn(app, @HC_Hz_EditFieldValueChanged, true);
            app.HC_Hz_EditField.Enable = 'off';
            app.HC_Hz_EditField.Value = 10000;
            app.HC_Switch = uiswitch(app.HighCutFilterPanel, 'slider');
            app.HC_Switch.ValueChangedFcn = createCallbackFcn(app, @HC_SwitchValueChanged, true);
            app.HC_Hz_Label = uilabel(app.HighCutFilterPanel);
            app.HC_Hz_Label.Enable = 'off';
            app.HC_Hz_Label.Text = 'Hz';
            app.HC_CutOffFrequency_Label = uilabel(app.HighCutFilterPanel);
            app.HC_CutOffFrequency_Label.Enable = 'off';
            app.HC_CutOffFrequency_Label.Text = 'CutOff Frequency';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Icon = fullfile(pathToMLAPP, 'Icons', 'Pause_Icon.png');
            app.PauseButton.Enable = 'off';
            app.PauseButton.Visible = 'off';
            app.PauseButton.Text = '';
            app.InitialLabel = uilabel(app.UIFigure);
            app.InitialLabel.Text = 'Load an audio file to begin';
            app.BellFilterLabel = uilabel(app.UIFigure);
            app.BellFilterLabel.Enable = 'off';
            app.BellFilterLabel.Text = 'Bell Filter';
            app.LowCutFilterLabel = uilabel(app.UIFigure);
            app.LowCutFilterLabel.Enable = 'off';
            app.LowCutFilterLabel.Text = 'Low Cut Filter';
            app.HighCutFilterLabel = uilabel(app.UIFigure);
            app.HighCutFilterLabel.Enable = 'off';
            app.HighCutFilterLabel.Text = 'High Cut Filter';
            app.OutGainSliderLabel = uilabel(app.UIFigure);
            app.OutGainSliderLabel.Text = 'Out Gain';
            app.OutGainSlider = uislider(app.UIFigure);
            app.OutGainSlider.Limits = [-40 20];
            app.OutGainSlider.ValueChangedFcn = createCallbackFcn(app, @OutGainSliderValueChanged, true);
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Icon = fullfile(pathToMLAPP, 'Icons', 'Microphone_Icon.png');
            app.RecordButton.Text = '';
            app.StopRecordButton = uibutton(app.UIFigure, 'push');
            app.StopRecordButton.ButtonPushedFcn = createCallbackFcn(app, @StopRecordButtonPushed, true);
            app.StopRecordButton.Icon = fullfile(pathToMLAPP, 'Icons', 'Microphone_Icon.png');
            app.StopRecordButton.Enable = 'off';
            app.StopRecordButton.Visible = 'off';
            app.StopRecordButton.Text = '';
            app.Meter = uiaudiometer(app.UIFigure);
            app.Meter.ValueUnits = '';
            app.dBFSLabel = uilabel(app.UIFigure);
            app.dBFSLabel.Text = 'dBFS';
            app.dBLabel = uilabel(app.UIFigure);
            app.dBLabel.Text = 'dB';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EQAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end